classdef OptionPricingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Project3OptionPricingUIFigure   matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        OptionpriceTab                  matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        CalculateButton                 matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        StockDataLabel                  matlab.ui.control.Label
        CalculatedOptionPriceLabel      matlab.ui.control.Label
        FurtherDataLabel                matlab.ui.control.Label
        OptionDataLabel                 matlab.ui.control.Label
        StrikepriceUSDLabel_2           matlab.ui.control.Label
        MaturityLabel                   matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        TradingdaysyearlyLabel_2        matlab.ui.control.Label
        AnnualizedinterestrateLabel_2   matlab.ui.control.Label
        SigmacalculatedlastyearLabel    matlab.ui.control.Label
        SigmaimpliedLabel               matlab.ui.control.Label
        labelPageA_6                    matlab.ui.control.Label
        labelPageA_7                    matlab.ui.control.Label
        labelPageA_1                    matlab.ui.control.Label
        SymbolLabel_2                   matlab.ui.control.Label
        EditField_5                     matlab.ui.control.EditField
        SymbolLabel_3                   matlab.ui.control.Label
        CurrentpriceUSDLabel            matlab.ui.control.Label
        CurrentpriceUSDLabel_4          matlab.ui.control.Label
        labelPageA_2                    matlab.ui.control.Label
        labelPageA_3                    matlab.ui.control.Label
        labelPageA_4                    matlab.ui.control.Label
        labelPageA_5                    matlab.ui.control.Label
        BlackScholesModelButtonGroup    matlab.ui.container.ButtonGroup
        NoValueLabel                    matlab.ui.control.Label
        MonteCarlosimulationButtonGroup  matlab.ui.container.ButtonGroup
        nEditFieldLabel                 matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        NoValueLabel_2                  matlab.ui.control.Label
        CoxRossRubinsteinoptionpricingmodelButtonGroup  matlab.ui.container.ButtonGroup
        nEditField_2Label               matlab.ui.control.Label
        nEditField_2                    matlab.ui.control.NumericEditField
        NoValueLabel_3                  matlab.ui.control.Label
        NoValueLabel_4                  matlab.ui.control.Label
        nEditField_2Label_2             matlab.ui.control.Label
        nEditField_2Label_3             matlab.ui.control.Label
        EffectsontheoptionpriceTab      matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        StockDataLabel_2                matlab.ui.control.Label
        FurtherDataLabel_2              matlab.ui.control.Label
        OptionDataLabel_2               matlab.ui.control.Label
        StrikepriceUSDLabel             matlab.ui.control.Label
        MaturityLabel_2                 matlab.ui.control.Label
        TradingdaysyearlyLabel          matlab.ui.control.Label
        AnnualizedinterestrateLabel     matlab.ui.control.Label
        SigmacalculatedlastyearLabel_2  matlab.ui.control.Label
        SigmaimpliedLabel_2             matlab.ui.control.Label
        labelPageB_10                   matlab.ui.control.Label
        labelPageB_11                   matlab.ui.control.Label
        labelPageB_2                    matlab.ui.control.Label
        SymbolLabel_4                   matlab.ui.control.Label
        SymbolLabel_5                   matlab.ui.control.Label
        CurrentpriceUSDLabel_2          matlab.ui.control.Label
        CurrentpriceUSDLabel_3          matlab.ui.control.Label
        labelPageB_4                    matlab.ui.control.Label
        labelPageB_5                    matlab.ui.control.Label
        labelPageB_6                    matlab.ui.control.Label
        labelPageB_9                    matlab.ui.control.Label
        DaystomaturityLabel             matlab.ui.control.Label
        labelPageB_7                    matlab.ui.control.Label
        OptionPricingModelButtonGroup   matlab.ui.container.ButtonGroup
        BlackScholesButton              matlab.ui.control.RadioButton
        MonteCarloButton                matlab.ui.control.RadioButton
        CoxRossRubinsteinButton         matlab.ui.control.RadioButton
        labelPageB_1                    matlab.ui.control.Label
        labelPageB_3                    matlab.ui.control.Label
        labelPageB_8                    matlab.ui.control.Label
        VariateParametersButtonGroup    matlab.ui.container.ButtonGroup
        checkBoxPageB_1                 matlab.ui.control.CheckBox
        checkBoxPageB_2                 matlab.ui.control.CheckBox
        checkBoxPageB_3                 matlab.ui.control.CheckBox
        checkBoxPageB_4                 matlab.ui.control.CheckBox
    end

 
    methods (Access = private)
        
        function updateChartB(app)
            
            variateS0 = app.checkBoxPageB_1.Value;
            variateR = app.checkBoxPageB_2.Value;
            variateSigma = app.checkBoxPageB_3.Value;
            variateT = app.checkBoxPageB_4.Value;
            
            S0 = str2double(app.labelPageB_2.Text);
            r = str2double(app.labelPageB_9.Text);
            sigma = str2double(app.labelPageB_10.Text);
            T = str2double(app.labelPageB_7.Text)/365;
            K = str2double(app.labelPageB_5.Text);
            n1 = app.nEditField.Value;
            n2 = app.nEditField_2.Value;
            
            app.UIAxes_2.cla;
             
            if ~(isnan(S0) || isnan(r) || isnan(sigma) || isnan(T) || isnan(K) || strcmp(n1,'No Value') || strcmp(n2,'No Value') || ~(variateS0 || variateR || variateSigma || variateT))
                
                selectedButton = app.OptionPricingModelButtonGroup.SelectedObject; 
                
                switch selectedButton.Text
                    
                    case 'Black-Scholes'
                        calcVar = @(S0, T,K,r,sigma) calcBlackScholes(S0, T,K,r,sigma); 
                    case 'Monte-Carlo'
                        n1 = app.nEditField.Value;
                        calcVar = @(S0, T,K,r,sigma) calcMonteCarlo(S0, T, K, r, sigma,n1);
                    case 'Cox-Ross-Rubinstein'
                        n2 = app.nEditField_2.Value;
                        putOrCall = 1;
                        calcVar = @(S0, T,K,r,sigma) calcCoxRossRubinstein(S0, T, K, r, sigma,n2,putOrCall);
                end
                
                x = 0:0.01:2; %For calculation
                xAxis = -100:1:100; %For x-Axis
                y = zeros(1, numel(x));
                
                hold(app.UIAxes_2,'on');
                linewidth = 2;
                legendString = {};
                
                if variateS0
                    for i = 1:numel(x)
                        y(i) = calcVar(S0*x(i), T, K, r, sigma);
                    end    
                    legendString = [legendString, 'S0'];
                    plot(app.UIAxes_2,xAxis,y,'-','LineWidth',linewidth,'Color',[0 0.4470 0.7410]);
                end
                
                if variateR
                    for i = 1:numel(x)
                        y(i) = calcVar(S0, T, K, r*x(i), sigma);
                    end    
                    legendString = [legendString, 'r'];
                    plot(app.UIAxes_2,xAxis,y,'-','LineWidth',linewidth,'Color',[0.6350 0.0780 0.1840]);
                end
                
                if variateSigma
                    for i = 1:numel(x)
                        y(i) = calcVar(S0, T, K, r, sigma*x(i));
                    end    
                    legendString = [legendString, 'sigma'];
                    plot(app.UIAxes_2,xAxis,y,'-','LineWidth',linewidth,'Color',[0.9290 0.6940 0.1250]);
                end
                
                if variateT
                    for i = 1:numel(x)
                        y(i) = calcVar(S0, T*x(i), K, r, sigma);
                    end    
                    legendString = [legendString, 'T'];
                    plot(app.UIAxes_2,xAxis,y,'-','LineWidth',linewidth,'Color',[0 153/255 0]);
                end
                
                app.UIAxes_2.Box = 'on';
                app.UIAxes_2.XGrid = 'on';
                app.UIAxes_2.YGrid = 'on';
                app.UIAxes_2.Title.String = 'Variation of S0, r, sigma and T';
                app.UIAxes_2.XLabel.String = 'Variation of parameter in %';
                app.UIAxes_2.YLabel.String = 'Option price in USD'; 
                
                legend(app.UIAxes_2,legendString,'Location','northwest');
                  
            end   
        
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
  
            symbolStock = app.EditField.Value;
            %symbolOption = app.EditField_5.Value;
            symbolOption = getCorrectSymbol(app.EditField_5.Value);
            app.labelPageB_1.Text = symbolStock;
            app.labelPageB_3.Text = symbolOption;
            
            stockData = getStockData(symbolStock);
            optionData = getOptionData(symbolOption);
            
            %Calculate Sigma
            trad_days_yearly = app.EditField_3.Value; %num of trading days in US 253
            app.labelPageB_8.Text = num2str(trad_days_yearly);
            sigma_daily = std(stockData.rollingdelta(2:trad_days_yearly+1));%calculated using data for 1 year %TODO
            sigma_yearly = sigma_daily * sqrt(trad_days_yearly);
               
            S0 = stockData.close (1); %Current stock value in dollars
            O0 = optionData.c_last_price;
            app.labelPageA_1.Text = num2str(S0);
            app.labelPageA_2.Text = num2str(O0);
            app.labelPageB_2.Text = num2str(S0);
            app.labelPageB_4.Text = num2str(O0);
            
            K = optionData.K; %Strike price in dollars
            app.labelPageA_3.Text = num2str(K);
            app.labelPageB_5.Text = num2str(K);
            
            maturity = optionData.maturity;
            app.labelPageA_4.Text = num2str(maturity);
            app.labelPageB_6.Text = num2str(maturity);
            
            
            numDays = days(datetime(maturity)-datetime(date));
            app.labelPageB_7.Text = num2str(numDays);
            T = numDays/365; %Time to expiration

            r = getRisklessYieldData(date, maturity); %current annualized yield 6 month treasury bill, Annualized interest rate (continuously compounded)
            r = r.r;
            
            app.labelPageA_5.Text = num2str(r);
            app.labelPageB_9.Text = num2str(r);
            
            sigma = sigma_yearly; %Annualized volatility 
            
            optionPriceBS = calcBlackScholes(S0, T, K, r, sigma);
            n1 = app.nEditField.Value;
            optionPriceMC = calcMonteCarlo(S0, T, K, r, sigma,n1);
            n2 = app.nEditField_2.Value;
            
            putOrCall = 1;
            [optionPriceCRR_EU,optionPriceCRR_AM] = calcCoxRossRubinstein(S0, T, K, r, sigma,n2,putOrCall);
 
            app.NoValueLabel.Text = [num2str(optionPriceBS) ' USD'];
            app.NoValueLabel_2.Text = [num2str(optionPriceMC) ' USD'];
            app.NoValueLabel_3.Text = [num2str(optionPriceCRR_EU) ' USD'];
            app.NoValueLabel_4.Text = [num2str(optionPriceCRR_AM) ' USD'];
            
            sigma_implied = calcImpliedVolatilityBS(S0, T, K, r, O0);
            
            app.labelPageA_6.Text = mat2str(round(sigma,5));
            app.labelPageA_7.Text = num2str(sigma_implied);
            app.labelPageB_10.Text = mat2str(round(sigma,5));
            app.labelPageB_11.Text = num2str(sigma_implied);
            
            %------------- Historical data -------------------------
            
            historicalData = getHistoricalOptionData(symbolOption);
            
            maxN = 100;%up to n trading days in past
            sigma_daily_hist = movstd(stockData.rollingdelta,[0 trad_days_yearly]); %calculated using data for 1 year %TODO
            sigma_yearly_hist = sigma_daily_hist * sqrt(trad_days_yearly);
            
            matrixMaturity = cellstr(repelem(maturity, maxN, 1));
            S0 = stockData.close(1:maxN);
            SDates = stockData.date(1:maxN);
            
            numDays = days(datetime(maturity)-datetime(SDates));
            T = numDays/365;
            K = optionData.K;
            rTable = getRisklessYieldData(SDates, matrixMaturity);
            r=rTable.r;
            
            sigma = sigma_yearly_hist(1:maxN);  
            
            C_calcT = datetime(SDates);
            C_calc = calcBlackScholes(S0, T, K, r, sigma);
            
            C_actT = datetime(historicalData.data.date);
            C_act = historicalData.data.close;
            
            app.UIAxes.cla;
            hold(app.UIAxes,'on');
            
            plot(app.UIAxes,C_actT,C_act,'-','LineWidth',2,'Color',[0.6350 0.0780 0.1840]);
            plot(app.UIAxes,C_calcT,C_calc,'-','LineWidth',2,'Color',[0.9290 0.6940 0.1250]);

            app.UIAxes.Box = 'on';
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.Title.String = 'Historical option data, real and calculated';
            app.UIAxes.XLabel.String = 'Date';
            app.UIAxes.YLabel.String = 'Option price in USD';
            %app.UIAxes.XDir = 'reverse';
            xtickformat(app.UIAxes','dd-MMM-yyyy');
    
            C_actT = sort(C_actT);
            C_calcT = sort(C_calcT);
            app.UIAxes.XLim = [max(C_actT(1) ,C_calcT(1));max(C_actT(end),C_calcT(end))];
            %app.UIAxes.YLim = [min([a b c]) max([a b c])];
            legend(app.UIAxes,{'Real data', 'Black-Scholes model'},'Location','northeast');
            
            %---------------------------------------------------------
            
            updateChartB(app);
            
        end

        % Selection changed function: OptionPricingModelButtonGroup
        function OptionPricingModelButtonGroupSelectionChanged(app, event)
            updateChartB(app);            
        end

        % Value changed function: checkBoxPageB_1
        function checkBoxPageB_1ValueChanged(app, event)
            updateChartB(app);
        end

        % Value changed function: checkBoxPageB_2
        function checkBoxPageB_2ValueChanged(app, event)
            updateChartB(app);
        end

        % Value changed function: checkBoxPageB_3
        function checkBoxPageB_3ValueChanged(app, event)
            updateChartB(app);
        end

        % Value changed function: checkBoxPageB_4
        function checkBoxPageB_4ValueChanged(app, event)
            updateChartB(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Project3OptionPricingUIFigure = uifigure('Visible', 'off');
            app.Project3OptionPricingUIFigure.Name = 'Project 3 (Option Pricing)';
            app.TabGroup = uitabgroup(app.Project3OptionPricingUIFigure);
            app.OptionpriceTab = uitab(app.TabGroup);
            app.OptionpriceTab.Title = 'Option price';
            app.UIAxes = uiaxes(app.OptionpriceTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CalculateButton = uibutton(app.OptionpriceTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.EditField = uieditfield(app.OptionpriceTab, 'text');
            app.EditField.Value = 'AMZN';
            app.StockDataLabel = uilabel(app.OptionpriceTab);
            app.StockDataLabel.Text = 'Stock Data:';
            app.CalculatedOptionPriceLabel = uilabel(app.OptionpriceTab);
            app.CalculatedOptionPriceLabel.Text = 'Calculated Option Price:';
            app.FurtherDataLabel = uilabel(app.OptionpriceTab);
            app.FurtherDataLabel.Text = 'Further Data:';
            app.OptionDataLabel = uilabel(app.OptionpriceTab);
            app.OptionDataLabel.Text = 'Option Data:';
            app.StrikepriceUSDLabel_2 = uilabel(app.OptionpriceTab);
            app.StrikepriceUSDLabel_2.Text = 'Strike price (USD)';
            app.MaturityLabel = uilabel(app.OptionpriceTab);
            app.MaturityLabel.Text = 'Maturity';
            app.EditField_3 = uieditfield(app.OptionpriceTab, 'numeric');
            app.EditField_3.Value = 253;
            app.TradingdaysyearlyLabel_2 = uilabel(app.OptionpriceTab);
            app.TradingdaysyearlyLabel_2.Text = 'Trading days yearly';
            app.AnnualizedinterestrateLabel_2 = uilabel(app.OptionpriceTab);
            app.AnnualizedinterestrateLabel_2.Text = 'Annualized interest rate';
            app.SigmacalculatedlastyearLabel = uilabel(app.OptionpriceTab);
            app.SigmacalculatedlastyearLabel.Text = 'Sigma calculated (last year)';
            app.SigmaimpliedLabel = uilabel(app.OptionpriceTab);
            app.SigmaimpliedLabel.Text = 'Sigma implied';
            app.labelPageA_6 = uilabel(app.OptionpriceTab);
            app.labelPageA_6.Text = 'No Value';
            app.labelPageA_7 = uilabel(app.OptionpriceTab);
            app.labelPageA_7.Text = 'No Value';
            app.labelPageA_1 = uilabel(app.OptionpriceTab);
            app.labelPageA_1.Text = 'No Value';
            app.SymbolLabel_2 = uilabel(app.OptionpriceTab);
            app.SymbolLabel_2.Text = 'Symbol';
            app.EditField_5 = uieditfield(app.OptionpriceTab, 'text');
            app.EditField_5.Value = 'AMZN200417C01900000';
            app.SymbolLabel_3 = uilabel(app.OptionpriceTab);
            app.SymbolLabel_3.Text = 'Symbol';
            app.CurrentpriceUSDLabel = uilabel(app.OptionpriceTab);
            app.CurrentpriceUSDLabel.Text = 'Current price (USD)';
            app.CurrentpriceUSDLabel_4 = uilabel(app.OptionpriceTab);
            app.CurrentpriceUSDLabel_4.Text = 'Current price (USD)';
            app.labelPageA_2 = uilabel(app.OptionpriceTab);
            app.labelPageA_2.Text = 'No Value';
            app.labelPageA_3 = uilabel(app.OptionpriceTab);
            app.labelPageA_3.Text = 'No Value';
            app.labelPageA_4 = uilabel(app.OptionpriceTab);
            app.labelPageA_4.Text = 'No Value';
            app.labelPageA_5 = uilabel(app.OptionpriceTab);
            app.labelPageA_5.Text = 'No Value';
            app.BlackScholesModelButtonGroup = uibuttongroup(app.OptionpriceTab);
            app.BlackScholesModelButtonGroup.Title = 'Black- Scholes Model';
            app.NoValueLabel = uilabel(app.BlackScholesModelButtonGroup);
            app.NoValueLabel.Text = 'No Value';
            app.MonteCarlosimulationButtonGroup = uibuttongroup(app.OptionpriceTab);
            app.MonteCarlosimulationButtonGroup.Title = 'Monte- Carlo simulation';
            app.nEditFieldLabel = uilabel(app.MonteCarlosimulationButtonGroup);
            app.nEditFieldLabel.Text = 'n = ';
            app.nEditField = uieditfield(app.MonteCarlosimulationButtonGroup, 'numeric');
            app.nEditField.Value = 1000;
            app.NoValueLabel_2 = uilabel(app.MonteCarlosimulationButtonGroup);
            app.NoValueLabel_2.Text = 'No Value';
            app.CoxRossRubinsteinoptionpricingmodelButtonGroup = uibuttongroup(app.OptionpriceTab);
            app.CoxRossRubinsteinoptionpricingmodelButtonGroup.Title = 'Cox-Ross-Rubinstein option-pricing model';
            app.nEditField_2Label = uilabel(app.CoxRossRubinsteinoptionpricingmodelButtonGroup);
            app.nEditField_2Label.Text = 'n = ';
            app.nEditField_2 = uieditfield(app.CoxRossRubinsteinoptionpricingmodelButtonGroup, 'numeric');
            app.nEditField_2.Value = 100;
            app.NoValueLabel_3 = uilabel(app.CoxRossRubinsteinoptionpricingmodelButtonGroup);
            app.NoValueLabel_3.Text = 'No Value';
            app.NoValueLabel_4 = uilabel(app.CoxRossRubinsteinoptionpricingmodelButtonGroup);
            app.NoValueLabel_4.Text = 'No Value';
            app.nEditField_2Label_2 = uilabel(app.CoxRossRubinsteinoptionpricingmodelButtonGroup);
            app.nEditField_2Label_2.Text = 'European:';
            app.nEditField_2Label_3 = uilabel(app.CoxRossRubinsteinoptionpricingmodelButtonGroup);
            app.nEditField_2Label_3.Text = 'American:';
            app.EffectsontheoptionpriceTab = uitab(app.TabGroup);
            app.EffectsontheoptionpriceTab.Title = 'Effects on the option price';
            app.UIAxes_2 = uiaxes(app.EffectsontheoptionpriceTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.StockDataLabel_2 = uilabel(app.EffectsontheoptionpriceTab);
            app.StockDataLabel_2.Text = 'Stock Data:';
            app.FurtherDataLabel_2 = uilabel(app.EffectsontheoptionpriceTab);
            app.FurtherDataLabel_2.Text = 'Further Data:';
            app.OptionDataLabel_2 = uilabel(app.EffectsontheoptionpriceTab);
            app.OptionDataLabel_2.Text = 'Option Data:';
            app.StrikepriceUSDLabel = uilabel(app.EffectsontheoptionpriceTab);
            app.StrikepriceUSDLabel.Text = 'Strike price (USD)';
            app.MaturityLabel_2 = uilabel(app.EffectsontheoptionpriceTab);
            app.MaturityLabel_2.Text = 'Maturity';
            app.TradingdaysyearlyLabel = uilabel(app.EffectsontheoptionpriceTab);
            app.TradingdaysyearlyLabel.Text = 'Trading days yearly';
            app.AnnualizedinterestrateLabel = uilabel(app.EffectsontheoptionpriceTab);
            app.AnnualizedinterestrateLabel.Text = 'Annualized interest rate';
            app.SigmacalculatedlastyearLabel_2 = uilabel(app.EffectsontheoptionpriceTab);
            app.SigmacalculatedlastyearLabel_2.Text = 'Sigma calculated (last year)';
            app.SigmaimpliedLabel_2 = uilabel(app.EffectsontheoptionpriceTab);
            app.SigmaimpliedLabel_2.Text = 'Sigma implied';
            app.labelPageB_10 = uilabel(app.EffectsontheoptionpriceTab);
            app.labelPageB_10.Text = 'No Value';
            app.labelPageB_11 = uilabel(app.EffectsontheoptionpriceTab);
            app.labelPageB_11.Text = 'No Value';
            app.labelPageB_2 = uilabel(app.EffectsontheoptionpriceTab);
            app.labelPageB_2.Text = 'No Value';
            app.SymbolLabel_4 = uilabel(app.EffectsontheoptionpriceTab);
            app.SymbolLabel_4.Text = 'Symbol';
            app.SymbolLabel_5 = uilabel(app.EffectsontheoptionpriceTab);
            app.SymbolLabel_5.Text = 'Symbol';
            app.CurrentpriceUSDLabel_2 = uilabel(app.EffectsontheoptionpriceTab);
            app.CurrentpriceUSDLabel_2.Text = 'Current price (USD)';
            app.CurrentpriceUSDLabel_3 = uilabel(app.EffectsontheoptionpriceTab);
            app.CurrentpriceUSDLabel_3.Text = 'Current price (USD)';
            app.labelPageB_4 = uilabel(app.EffectsontheoptionpriceTab);
            app.labelPageB_4.Text = 'No Value';
            app.labelPageB_5 = uilabel(app.EffectsontheoptionpriceTab);
            app.labelPageB_5.Text = 'No Value';
            app.labelPageB_6 = uilabel(app.EffectsontheoptionpriceTab);
            app.labelPageB_6.Text = 'No Value';
            app.labelPageB_9 = uilabel(app.EffectsontheoptionpriceTab);
            app.labelPageB_9.Text = 'No Value';
            app.DaystomaturityLabel = uilabel(app.EffectsontheoptionpriceTab);
            app.DaystomaturityLabel.Text = 'Days to maturity';
            app.labelPageB_7 = uilabel(app.EffectsontheoptionpriceTab);
            app.labelPageB_7.Text = 'No Value';
            app.OptionPricingModelButtonGroup = uibuttongroup(app.EffectsontheoptionpriceTab);
            app.OptionPricingModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionPricingModelButtonGroupSelectionChanged, true);
            app.OptionPricingModelButtonGroup.Title = 'Option Pricing Model';
            app.BlackScholesButton = uiradiobutton(app.OptionPricingModelButtonGroup);
            app.BlackScholesButton.Text = 'Black-Scholes';
            app.BlackScholesButton.Value = true;
            app.MonteCarloButton = uiradiobutton(app.OptionPricingModelButtonGroup);
            app.MonteCarloButton.Text = 'Monte-Carlo';
            app.CoxRossRubinsteinButton = uiradiobutton(app.OptionPricingModelButtonGroup);
            app.CoxRossRubinsteinButton.Text = 'Cox-Ross-Rubinstein';
            app.labelPageB_1 = uilabel(app.EffectsontheoptionpriceTab);
            app.labelPageB_1.Text = 'No Value';
            app.labelPageB_3 = uilabel(app.EffectsontheoptionpriceTab);
            app.labelPageB_3.Text = 'No Value';
            app.labelPageB_8 = uilabel(app.EffectsontheoptionpriceTab);
            app.labelPageB_8.Text = 'No Value';
            app.VariateParametersButtonGroup = uibuttongroup(app.EffectsontheoptionpriceTab);
            app.VariateParametersButtonGroup.Title = 'Variate Parameters';
            app.checkBoxPageB_1 = uicheckbox(app.VariateParametersButtonGroup);
            app.checkBoxPageB_1.ValueChangedFcn = createCallbackFcn(app, @checkBoxPageB_1ValueChanged, true);
            app.checkBoxPageB_1.Text = 'Current stock price';
            app.checkBoxPageB_1.Value = true;
            app.checkBoxPageB_2 = uicheckbox(app.VariateParametersButtonGroup);
            app.checkBoxPageB_2.ValueChangedFcn = createCallbackFcn(app, @checkBoxPageB_2ValueChanged, true);
            app.checkBoxPageB_2.Text = 'Annualized interest rate';
            app.checkBoxPageB_2.Value = true;
            app.checkBoxPageB_3 = uicheckbox(app.VariateParametersButtonGroup);
            app.checkBoxPageB_3.ValueChangedFcn = createCallbackFcn(app, @checkBoxPageB_3ValueChanged, true);
            app.checkBoxPageB_3.Text = 'Sigma calculated (last year)';
            app.checkBoxPageB_3.Value = true;
            app.checkBoxPageB_4 = uicheckbox(app.VariateParametersButtonGroup);
            app.checkBoxPageB_4.ValueChangedFcn = createCallbackFcn(app, @checkBoxPageB_4ValueChanged, true);
            app.checkBoxPageB_4.Text = 'Time to maturity';
            app.checkBoxPageB_4.Value = true;
            % Show the figure after all components are created
            app.Project3OptionPricingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptionPricingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Project3OptionPricingUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Project3OptionPricingUIFigure)
        end
    end
end